classdef FleetManager_240201124 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        GridLayout               matlab.ui.container.GridLayout
        LeftPanel                matlab.ui.container.Panel
        arpmanoktalarCheckBox    matlab.ui.control.CheckBox
        GidilenYolCheckBox       matlab.ui.control.CheckBox
        PlanlanmRotaCheckBox     matlab.ui.control.CheckBox
        SimlasyonubalatButton    matlab.ui.control.Button
        YolPlanlaButton          matlab.ui.control.Button
        RobotlarYerletirButton   matlab.ui.control.Button
        DarKoridorButton         matlab.ui.control.Button
        SimlasyonuDurdurButton   matlab.ui.control.Button
        DropDown_2               matlab.ui.control.DropDown
        DeadlockAlgoritmasLabel  matlab.ui.control.Label
        YolBulmaAlgoritmasLabel  matlab.ui.control.Label
        DropDown                 matlab.ui.control.DropDown
        SeedseipkullanmakiinseediyazpRandomMapetklaynzLabel  matlab.ui.control.Label
        SeedEditField            matlab.ui.control.NumericEditField
        SeedEditFieldLabel       matlab.ui.control.Label
        RandomMapButton          matlab.ui.control.Button
        AlgoritmaAyarlarLabel    matlab.ui.control.Label
        MapAyarlarLabel          matlab.ui.control.Label
        RightPanel               matlab.ui.container.Panel
        TabGroup                 matlab.ui.container.TabGroup
        Tab                      matlab.ui.container.Tab
        UIAxes                   matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
properties (Access = private)
    % --- Map / Seeds ---
    GridSize = 50
    MapData = []          % 0 boş, 1 engel
    MainSeed = []
    RobotSeed = []
    IsRandomMap = false
    SimPaths = {} 
    PathAlgo = 'AStar'   % 'AStar' | 'Dijkstra' | 'HybridAStar'

        % --- HUD / Zaman ---
    SimStartTic = [];          % tic handle

    
    % --- Deadlock sayacı: olay bazlı ---
    DeadlockActive = false;    % deadlock şu an sürüyor mu?
    LastDeadlockKeys = strings(0,1);   % geçen tick’in deadlock component "imzaları"


    % --- Robots / Paths ---
    NumRobots = 10
    RobotData = struct('Start', {}, 'Goal', {}, 'Color', {})
    PlannedPaths = {}     % {i} = Nx2 grid path
    OriginalPaths = {}    % sim için yedek
    HistoryPaths = {}     % {i} = gidilen yol
    PathHandles = {}
    PathIdx = []          % her robotun index'i
    
    
    RobotHandles = gobjects(0);
    GoalHandles  = gobjects(0);
    RobotTextHandles = gobjects(0);


    % --- Simulation / Metrics ---
    IsRunning = false
    StepCounter = 0
    TotalConflicts = 0
    TotalWaitTime = 0
    TotalDeadlocks = 0

    % --- Graph cache (Dijkstra) ---
    FreeIdMap = []        % (r,c)->nodeId
    FreeR = []            % nodeId->r
    FreeC = []            % nodeId->c
    FreeGraph = []        % graph object

    HudTextHandles = gobjects(0);   % 1x3 text
   
    SimElapsed = 0;                 % pause/resume için (opsiyonel)


    % --- Wait-for çizimleri ---
    WaitForHandles = gobjects(0)
    
    % zaman ayarı

    SimTimer 
    TickPeriod = 0.15  % saniye

    MapImgHandle = []


    %simulasyon buton ayarı
    HasSimState = false

   
    Priority = [];                     % 1..K öncelik (küçük = yüksek)
    WaitCount = []

    NoMoveTicks = 0;
    MaxNoMoveTicks = 40;   % 80 tick * 0.15s ~= 12s hiç hareket yoksa "tıkandı" say


        % --- çizim handle'ları ---
    PlannedPathHandles = {}     % dashed (planlanan)
    TraveledPathHandles = {}    % solid (gidilen yol)
    DeadlockMarkHandles = gobjects(0); % kırmızı daireler

    DeadlockCellsAcc = zeros(0,2);   % Mx2 [r c]
    DeadlockPairsAcc = zeros(0,4);   % Px4 [r1 c1 r2 c2] (normalize edilecek)


    WheelBase = 0.3;  % wheelBase=0.6m, cell=1.0m
    CellSize  = 1.0;

end

    
    methods (Access = private)


        function keys = deadlockComponentKeys(app, pairs, singles)
    % pairs: Nx4 [r1 c1 r2 c2]
    % singles: Mx2 [r c]
    % Çıktı: her component için stable string key (aynı component aynı key)

    pairs = app.normalizePairs(pairs);
    pairs = unique(pairs,'rows');
    singles = unique(singles,'rows');

    nPairs = size(pairs,1);

    pts = zeros(0,2);
    if nPairs>0
        pts = [pairs(:,1:2); pairs(:,3:4)];
    end
    if ~isempty(singles)
        pts = [pts; singles];
    end

    if isempty(pts)
        keys = strings(0,1);
        return;
    end

    [U, ~, idxU] = unique(pts,'rows');  % U = Mx2 [r c]
    M = size(U,1);

    % node single mı?
    isSingleNode = false(M,1);
    if ~isempty(singles)
        idxSingles = idxU(2*nPairs+1:end);
        isSingleNode(idxSingles) = true;
    end

    % edges
    if nPairs>0
        ia = idxU(1:nPairs);
        ib = idxU(nPairs+1:2*nPairs);
        keep = (ia~=ib);
        ia = ia(keep); ib = ib(keep);
    else
        ia=[]; ib=[];
    end

    if ~isempty(ia)
        G = graph(ia, ib, [], M);
        comp = conncomp(G);
    else
        comp = (1:M);
    end

    C = max(comp);
    tmp = strings(0,1);

    for cc = 1:C
        nodes = find(comp==cc);

        % kural: >=2 node => component deadlock
        % 1 node => sadece single ise deadlock
        if numel(nodes) >= 2
            okDraw = true;
        else
            okDraw = isSingleNode(nodes);
        end
        if ~okDraw, continue; end

        ptsC = U(nodes,:);
        ptsC = sortrows(ptsC,[1 2]); % stabil sıralama

        % key: "r,c;r,c;..."
        tmp(end+1,1) = join(string(ptsC(:,1)) + "," + string(ptsC(:,2)),";"); %#ok<AGROW>
    end

    keys = tmp;
end


        function pairsN = normalizePairs(app, pairs) %#ok<INUSD>
    % pairs: Nx4 [r1 c1 r2 c2]
    % A-B ile B-A aynı sayılacak şekilde normalize eder

    if isempty(pairs)
        pairsN = zeros(0,4);
        return;
    end

    pairsN = pairs;

    for ii = 1:size(pairs,1)
        r1 = pairs(ii,1); c1 = pairs(ii,2);
        r2 = pairs(ii,3); c2 = pairs(ii,4);

        % (r,c) lexicographic karşılaştırma
        if (r2 < r1) || (r2 == r1 && c2 < c1)
            pairsN(ii,:) = [r2 c2 r1 c1];
        end
    end
end


        function initHud(app)
    ax = app.UIAxes;

    % zaten varsa çık
    if ~isempty(app.HudTextHandles) && all(isgraphics(app.HudTextHandles)) && numel(app.HudTextHandles)==3
        return;
    end

    % eski/bozuksa temizle
    if ~isempty(app.HudTextHandles)
        for k=1:numel(app.HudTextHandles)
            if isgraphics(app.HudTextHandles(k)), delete(app.HudTextHandles(k)); end
        end
    end

    hold(ax,'on');

    % 3 kutu: soldan sağa
    x0 = 0.02; y0 = 0.985; gap = 0.015;
    xs = [x0, x0+0.33+gap, x0+0.66+2*gap];

    hs = gobjects(1,3);
    for i=1:3
        hs(i) = text(ax, xs(i), y0, "", ...
            'Units','normalized', ...
            'HorizontalAlignment','left', ...
            'VerticalAlignment','top', ...
            'FontName','Consolas', ...
            'FontSize',12, ...
            'FontWeight','bold', ...
            'Color',[1 1 1], ...
            'BackgroundColor',[0.08 0.08 0.08], ...
            'Margin',8, ...
            'Clipping','off', ...
            'HitTest','off', ...
            'PickableParts','none');
    end

    app.HudTextHandles = hs;

    hold(ax,'off');
end


function updateHud(app)
    % Güvenlik
    if isempty(app) || ~isvalid(app)
        return;
    end
    if isempty(app.UIAxes) || ~isvalid(app.UIAxes)
        return;
    end

    % HUD yoksa oluştur
    if isempty(app.HudTextHandles) || numel(app.HudTextHandles) ~= 3 || ~all(isgraphics(app.HudTextHandles))
        try
            initHud(app);
        catch
            return;
        end
    end

    % Süre hesabı (pause/resume destekli)
    t = 0;
    if ~isempty(app.SimElapsed) && isfinite(app.SimElapsed)
        t = t + app.SimElapsed;
    end
    if ~isempty(app.SimStartTic)
        try
            t = t + toc(app.SimStartTic);
        catch
        end
    end

    % Robot/goal durumu
    K = numel(app.RobotData);
    atGoalCount = 0;
    if K > 0 && ~isempty(app.HistoryPaths)
        for i = 1:K
            if numel(app.HistoryPaths) >= i && ~isempty(app.HistoryPaths{i})
                cur = app.HistoryPaths{i}(end,:);
                g   = app.RobotData(i).Goal;
                if isequal(cur, g)
                    atGoalCount = atGoalCount + 1;
                end
            end
        end
    end

    % Wait bilgisi (toplam bekleme tickleri gibi göster)
    waitSum = 0;
    if ~isempty(app.WaitCount)
        waitSum = sum(app.WaitCount(~isnan(app.WaitCount)));
    end

    % Durum yazısı
    if app.IsRunning
        stateStr = "RUN";
    else
        stateStr = "PAUSE";
    end

    % Deadlock anlık durum
    if app.DeadlockActive
        deadNowStr = "YES";
    else
        deadNowStr = "NO";
    end

    % 3 kutuyu doldur
    s1 = sprintf("Süre: %.2f s\nTick: %d\nDurum: %s", ...
        t, app.StepCounter, stateStr);

    s2 = sprintf("Robot: %d\nHedefte: %d/%d\nNoMove: %d/%d", ...
        K, atGoalCount, K, app.NoMoveTicks, app.MaxNoMoveTicks);

    s3 = sprintf("Deadlock: %d\nAnlık: %s\nWaitSum: %d", ...
        app.TotalDeadlocks, deadNowStr, waitSum);

    % Yazdır
    try
        app.HudTextHandles(1).String = s1;
        app.HudTextHandles(2).String = s2;
        app.HudTextHandles(3).String = s3;
    catch
        % UI kapanırken vs. sessiz geç
    end
end


        function map = generateRandomMap(app, seed)
    rng(seed);

    N = app.GridSize;
    map = zeros(N, N); % 0 boş, 1 engel

    targetOcc = 0.20;                 % %15-%30 arası tut
    targetOcc = max(0.15, min(0.30, targetOcc));
    maxAttempts = 8000;
    attempts = 0;

    while (sum(map(:))/numel(map)) < targetOcc && attempts < maxAttempts
        attempts = attempts + 1;

        % küçük engeller daha olası: rand^3
        maxS = 10;
        w = max(2, ceil(maxS * (rand^3)));
        h = max(2, ceil(maxS * (rand^3)));

        x = randi([1, N]); y = randi([1, N]);
        x2 = min(N, x + w - 1);
        y2 = min(N, y + h - 1);

        p = rand();

        if p < 0.35
            % (1) Dikdörtgen blok
            map(y:y2, x:x2) = 1;

        elseif p < 0.60
            % (2) L / U benzeri
            map(y:y2, x) = 1;
            map(y2, x:x2) = 1;
            if rand() < 0.5
                map(y:y2, x2) = 1; % U gibi
            end

        elseif p < 0.80
            % (3) Paralel duvarlar (dar geçit)
            gap = randi([1, 3]);
            xw = min(N, x + randi([1, 3]));
            map(y:y2, x) = 1;
            if x + gap <= N
                map(y:y2, x + gap) = 1;
            end
            map(y:y2, xw) = 1;

        else
            % (4) Dairesel/Eliptik engel
            [X, Y] = meshgrid(x:x2, y:y2);
            cx = (x + x2)/2; cy = (y + y2)/2;
            rx = max(1, (x2 - x + 1)/2.2);
            ry = max(1, (y2 - y + 1)/2.2);
            mask = ((X - cx).^2)/(rx^2) + ((Y - cy).^2)/(ry^2) <= 1;
            map(y:y2, x:x2) = map(y:y2, x:x2) | mask;
        end
    end

    % --- Zorunlu kalite: bağlantı (izole cepler olmasın) ---
    map = fixConnectivity(app, map);

    % --- Zorunlu kalite: 1-2 hücre dead-end temizliği ---
    map = pruneShortDeadEnds(app, map, 2, 0.15);
end

function map = fixConnectivity(app, map)
    N = size(map,1);
    free = (map == 0);
    [R, C] = find(free);
    if isempty(R), return; end

    id = zeros(N,N);
    for i=1:numel(R)
        id(R(i), C(i)) = i;
    end

    s = []; t = [];
    for i=1:numel(R)
        r = R(i); c = C(i);
        if c < N && free(r,c+1), s(end+1)=i; t(end+1)=id(r,c+1); end
        if r < N && free(r+1,c), s(end+1)=i; t(end+1)=id(r+1,c); end
    end
    G = graph(s, t, [], numel(R));
    bins = conncomp(G);

    if max(bins) <= 1, return; end

    mainIdx = find(bins == mode(bins), 1); % en büyük komponent
    mainR = R(mainIdx); mainC = C(mainIdx);

    for b = 1:max(bins)
        if b == bins(mainIdx), continue; end
        otherIdx = find(bins == b, 1);
        r2 = R(otherIdx); c2 = C(otherIdx);

        % koridor aç (engel -> boş yap)
        map(r2, min(c2,mainC):max(c2,mainC)) = 0;
        map(min(r2,mainR):max(r2,mainR), mainC) = 0;
    end
end

function map = pruneShortDeadEnds(app, map, maxLen, keepProb)
    N = size(map,1);

    changed = true;
    while changed
        changed = false;
        free = (map==0);

        for r=2:N-1
            for c=2:N-1
                if ~free(r,c), continue; end

                nb = [free(r+1,c) free(r-1,c) free(r,c+1) free(r,c-1)];
                if sum(nb) ~= 1, continue; end % dead-end değilse geç

                % küçük ihtimalle bırak
                if rand() < keepProb, continue; end

                rr = r; cc = c;

                for k=1:maxLen
                    % sınır güvenliği
                    if rr < 1 || rr > N || cc < 1 || cc > N
                        break;
                    end
                    if map(rr,cc) == 1
                        break;
                    end

                    % bu hücreyi kapat
                    map(rr,cc) = 1;
                    changed = true;

                    % güncel free
                    free(rr,cc) = false;

                    % bir sonraki hücreyi bul (bounds-check şart)
                    nexts = [rr+1 cc; rr-1 cc; rr cc+1; rr cc-1];
                    found = false;

                    for ii=1:4
                        r2 = nexts(ii,1);
                        c2 = nexts(ii,2);

                        if r2 < 1 || r2 > N || c2 < 1 || c2 > N
                            continue;
                        end

                        if free(r2,c2)
                            rr = r2; cc = c2;
                            found = true;
                            break;
                        end
                    end

                    if ~found
                        break;
                    end
                end
            end
        end
    end
end


function drawMap(app, titleStr)
    ax = app.UIAxes;

    if isempty(app.MapImgHandle) || ~isgraphics(app.MapImgHandle)
        % ilk kez çiz
        app.MapImgHandle = imagesc(ax, app.MapData);
        colormap(ax, [0 0 0; 1 1 1]);
        set(ax, 'YDir', 'normal');

        N = app.GridSize;
        axis(ax, 'image');
        axis(ax, 'tight');
        xlim(ax, [0.5 N+0.5]);
        ylim(ax, [0.5 N+0.5]);
        ax.LooseInset = [0 0 0 0];
        grid(ax, 'on');
        xlabel(ax, 'X [cells]');
        ylabel(ax, 'Y [cells]');
    else
        % sadece haritayı güncelle
        app.MapImgHandle.CData = app.MapData;
    end

    title(ax, titleStr);
end


  function buildFreeGraph(app)
    map = app.MapData;
    N = size(map,1);

    [R,C] = find(map==0);
    app.FreeR = R; app.FreeC = C;

    id = zeros(N,N);
    for i=1:numel(R)
        id(R(i),C(i)) = i;
    end
    app.FreeIdMap = id;

    s=[]; t=[];
    for i=1:numel(R)
        r=R(i); c=C(i);
        if c<N && map(r,c+1)==0, s(end+1)=i; t(end+1)=id(r,c+1); end
        if r<N && map(r+1,c)==0, s(end+1)=i; t(end+1)=id(r+1,c); end
    end

    app.FreeGraph = graph(s,t,[],numel(R)); % ağırlıklar=1
end

function pathRC = dijkstraPath(app, startRC, goalRC)
    idS = app.FreeIdMap(startRC(1), startRC(2));
    idG = app.FreeIdMap(goalRC(1), goalRC(2));
    if idS==0 || idG==0
        pathRC = [];
        return;
    end

    p = shortestpath(app.FreeGraph, idS, idG);
    if isempty(p)
        pathRC = [];
    else
        pathRC = [app.FreeR(p), app.FreeC(p)];
    end
end
  
function dist = bfsDist(app, startRC)
    % 4-yön BFS mesafesi (engel=1, boş=0)
    N   = app.GridSize;
    occ = app.MapData;

    dist = inf(N,N);

    sr = startRC(1);
    sc = startRC(2);

    % start geçerli mi?
    if sr < 1 || sr > N || sc < 1 || sc > N
        return;
    end
    if occ(sr,sc) == 1
        return;
    end

    % BFS queue (prealloc)
    q = zeros(N*N, 2, 'int16');
    head = 1; tail = 1;

    dist(sr,sc) = 0;
    q(tail,:) = int16([sr sc]);

    dirs = int16([ 1 0; -1 0; 0 1; 0 -1 ]);

    while head <= tail
        r = q(head,1);
        c = q(head,2);
        head = head + 1;

        dnext = dist(r,c) + 1;

        for k = 1:4
            r2 = r + dirs(k,1);
            c2 = c + dirs(k,2);

            if r2 < 1 || r2 > N || c2 < 1 || c2 > N
                continue;
            end
            if occ(r2,c2) == 1
                continue;
            end
            if isfinite(dist(r2,c2))
                continue; % zaten ziyaret edildi
            end

            dist(r2,c2) = dnext;
            tail = tail + 1;
            q(tail,:) = int16([r2 c2]);
        end
    end
end


% yol bulma algoritmaları


% A* (grid)
%
%
%

% A* (grid)
function pathRC = astarPath4or8(app, startRC, goalRC, allowDiag)
    N = app.GridSize;
    occ = app.MapData; % 0 free, 1 obs

    if occ(startRC(1),startRC(2))==1 || occ(goalRC(1),goalRC(2))==1
        pathRC = []; return;
    end

    if allowDiag
        dirs = [ 1 0; -1 0; 0 1; 0 -1; 1 1; 1 -1; -1 1; -1 -1];
        stepCost = [1 1 1 1 sqrt(2) sqrt(2) sqrt(2) sqrt(2)];
    else
        dirs = [ 1 0; -1 0; 0 1; 0 -1];
        stepCost = [1 1 1 1];
    end

    gScore = inf(N,N);
    fScore = inf(N,N);
    cameR  = zeros(N,N,'int16');
    cameC  = zeros(N,N,'int16');
    cameOK = false(N,N);

    sr=startRC(1); sc=startRC(2);
    gr=goalRC(1);  gc=goalRC(2);

    gScore(sr,sc)=0;
    fScore(sr,sc)=heur(sr,sc,gr,gc);   % <-- artık nested heur

    open = false(N,N);
    open(sr,sc)=true;

    while any(open,'all')
        [or,oc] = find(open);

        bestIdx = 1;
        bestVal = fScore(or(1),oc(1));
        for k=2:numel(or)
            v = fScore(or(k),oc(k));
            if v < bestVal
                bestVal = v; bestIdx = k;
            end
        end

        r = or(bestIdx); c = oc(bestIdx);

        if r==gr && c==gc
            pathRC = reconstructPath(cameR,cameC,cameOK,startRC,goalRC);
            return;
        end

        open(r,c)=false;

        for k=1:size(dirs,1)
            r2 = r + dirs(k,1);
            c2 = c + dirs(k,2);
            if r2<1||r2>N||c2<1||c2>N, continue; end
            if occ(r2,c2)==1, continue; end

            tentative = gScore(r,c) + stepCost(k);

            if tentative < gScore(r2,c2)
                gScore(r2,c2) = tentative;
                fScore(r2,c2) = tentative + heur(r2,c2,gr,gc); % <-- nested heur
                cameR(r2,c2)  = r;
                cameC(r2,c2)  = c;
                cameOK(r2,c2) = true;
                open(r2,c2)   = true;
            end
        end
    end

    pathRC = []; % yok

    % --------- NESTED HELPERS (BUNLAR SAYESİNDE "Undefined heur" BİTER) ---------
    function h = heur(r,c, gr,gc)
        dr = abs(gr-r); dc = abs(gc-c);
        if allowDiag
            % octile (diag varsa)
            h = (dr+dc) + (sqrt(2)-2)*min(dr,dc);
        else
            % manhattan (diag yoksa)
            h = dr+dc;
        end
    end

    function path = reconstructPath(cameR,cameC,cameOK, startRC, goalRC)
        r = goalRC(1); c = goalRC(2);
        if ~(r==startRC(1) && c==startRC(2)) && ~cameOK(r,c)
            path = []; return;
        end
        path = [r c];
        while ~(r==startRC(1) && c==startRC(2))
            pr = cameR(r,c); pc = cameC(r,c);
            r = pr; c = pc;
            path = [r c; path]; %#ok<AGROW>
        end
    end
end


%dijikstra
%
%
%

function pathRC = dijkstraGrid(app, startRC, goalRC)
    % graph cache yoksa kur
    if isempty(app.FreeGraph) || isempty(app.FreeIdMap)
        buildFreeGraph(app);
    end
    pathRC = dijkstraPath(app, startRC, goalRC);
end


% hybrid sistem 
%
%
%

function pathRC = hybridTurnCostAStar(app, startRC, goalRC)
    % Durum: (r,c,dir)  dir=1..4
    N = app.GridSize;
    occ = app.MapData;

    if occ(startRC(1),startRC(2))==1 || occ(goalRC(1),goalRC(2))==1
        pathRC=[]; return;
    end

    dirs = [ 1 0; -1 0; 0 1; 0 -1]; % 4 yön
    Kdir = 4;

    g = inf(N,N,Kdir);
    f = inf(N,N,Kdir);

    came   = zeros(N,N,Kdir,3,'int16'); % prev r,c,dir
    cameOK = false(N,N,Kdir);

    sr=startRC(1); sc=startRC(2);
    gr=goalRC(1);  gc=goalRC(2);

    initDir = guessDir(sr,sc,gr,gc);

    g(sr,sc,initDir)=0;
    f(sr,sc,initDir)=manh(sr,sc,gr,gc);

    open=false(N,N,Kdir);
    open(sr,sc,initDir)=true;

    turnPenalty = 0.6;   % dönmek pahalı (arttır: daha “araba”)
    stepCost = 1.0;

    while any(open,'all')
        idxAll = find(open);
        [or,oc,od] = ind2sub(size(open), idxAll);

        bestIdx=1;
        bestVal=f(or(1),oc(1),od(1));
        for k=2:numel(or)
            v=f(or(k),oc(k),od(k));
            if v<bestVal
                bestVal=v; bestIdx=k;
            end
        end

        r=or(bestIdx); c=oc(bestIdx); d=od(bestIdx);

        if r==gr && c==gc
            pathRC = reconstructHybrid(came,cameOK,[sr sc initDir],[gr gc d]);
            return;
        end

        open(r,c,d)=false;

        for nd=1:Kdir
            r2=r+dirs(nd,1); c2=c+dirs(nd,2);
            if r2<1||r2>N||c2<1||c2>N, continue; end
            if occ(r2,c2)==1, continue; end

            turnCost = (nd~=d)*turnPenalty;
            tentative = g(r,c,d) + stepCost + turnCost;

            if tentative < g(r2,c2,nd)
                g(r2,c2,nd)=tentative;
                f(r2,c2,nd)=tentative + manh(r2,c2,gr,gc);
                came(r2,c2,nd,:) = int16([r c d]);
                cameOK(r2,c2,nd)=true;
                open(r2,c2,nd)=true;
            end
        end
    end

    pathRC=[];

    % --------- NESTED HELPERS (Undefined guessDir biter) ---------
    function d = guessDir(r,c, gr,gc)
        dr = gr-r; dc = gc-c;
        if abs(dr) >= abs(dc)
            if dr>=0, d=1; else, d=2; end
        else
            if dc>=0, d=3; else, d=4; end
        end
    end

    function h = manh(r,c, gr,gc)
        h = abs(gr-r)+abs(gc-c);
    end

    function path = reconstructHybrid(came,cameOK, startState, goalState)
        r=goalState(1); c=goalState(2); d=goalState(3);
        sr=startState(1); sc=startState(2); sd=startState(3);

        if ~(r==sr && c==sc && d==sd) && ~cameOK(r,c,d)
            path=[]; return;
        end

        states = [r c d];
        while ~(r==sr && c==sc && d==sd)
            prev = squeeze(came(r,c,d,:))';
            r = prev(1); c = prev(2); d = prev(3);
            states = [r c d; states]; %#ok<AGROW>
        end

        path = states(:,1:2);
    end
end


function d = guessDir(~, r,c, gr,gc)
    dr = gr-r; dc = gc-c;
    if abs(dr) >= abs(dc)
        if dr>=0, d=1; else, d=2; end
    else
        if dc>=0, d=3; else, d=4; end
    end
end

function h = manh(~, r,c, gr,gc)
    h = abs(gr-r)+abs(gc-c);
end

function path = reconstructHybrid(~, came,cameOK, startState, goalState)
    % state = [r c dir]
    r=goalState(1); c=goalState(2); d=goalState(3);
    sr=startState(1); sc=startState(2); sd=startState(3);

    if ~(r==sr && c==sc && d==sd) && ~cameOK(r,c,d)
        path=[]; return;
    end

    states = [r c d];
    while ~(r==sr && c==sc && d==sd)
        prev = squeeze(came(r,c,d,:))';
        r = prev(1); c = prev(2); d = prev(3);
        states = [r c d; states]; %#ok<AGROW>
    end

    path = states(:,1:2); % dir at
end

    % zaman falan işi anlarsın ya

    
function simTick(app)
    % ===================== HARD SAFETY =====================
    if isempty(app) || ~isvalid(app), return; end
    if ~app.IsRunning, return; end

    K = numel(app.RobotData);
    if K <= 0, return; end

    dl = app.DropDown_2.Value;  % 'Bekle ve Dene' | 'Öncelik Bazlı' | 'Dinamik Rerouting'

    % ===================== TICK COUNTER =====================
    app.StepCounter = app.StepCounter + 1;

    % ===================== INIT ARRAYS =====================
    if isempty(app.WaitCount) || numel(app.WaitCount) ~= K
        app.WaitCount = zeros(1,K);
    end
    if isempty(app.SimPaths) || numel(app.SimPaths) ~= K
        app.SimPaths = app.PlannedPaths;
    end
    if isempty(app.PathIdx) || numel(app.PathIdx) ~= K
        app.PathIdx = ones(1,K);
    end
    if isempty(app.HistoryPaths) || numel(app.HistoryPaths) ~= K
        app.HistoryPaths = cell(1,K);
    end
    if isempty(app.NoMoveTicks) || ~isfinite(app.NoMoveTicks)
        app.NoMoveTicks = 0;
    end

    % HUD
    try initHud(app); catch, end

    % ===================== CURRENT / GOALS =====================
    curPos  = zeros(K,2);
    goalPos = zeros(K,2);
    atGoal  = false(1,K);

    for i=1:K
        s = app.RobotData(i).Start;
        g = app.RobotData(i).Goal;
        goalPos(i,:) = g;

        if isempty(app.HistoryPaths{i})
            app.HistoryPaths{i} = s;
        end

        curPos(i,:) = app.HistoryPaths{i}(end,:);
        atGoal(i)   = isequal(curPos(i,:), g);
    end

    % ===================== PRIORITY (SABİT) =====================
    % Öncelik: düşük = daha yüksek öncelik.
    % İlk tick’te (veya bozulduysa) path uzunluğuna göre sabitle.
    if isempty(app.Priority) || numel(app.Priority) ~= K || app.StepCounter == 1
        plen = inf(K,1);
        for i=1:K
            if numel(app.SimPaths) >= i && ~isempty(app.SimPaths{i}) && size(app.SimPaths{i},2)==2
                plen(i) = size(app.SimPaths{i},1);
            end
        end
        [~, ordP] = sortrows([plen (1:K)'], [1 2]);
        pr = zeros(1,K);
        for r=1:K
            pr(ordP(r)) = r;
        end
        app.Priority = pr;
    end

    % ===================== INTENT (NEXT CELL) =====================
    nextPos     = curPos;
    wantsMove   = false(1,K);
    needReplan  = false(1,K);   % path yok / uyumsuz / engel / out-of-bounds
    forceReplan = false(1,K);   % özellikle path mismatch (yol vermeden sonra) gibi

    for i=1:K
        if atGoal(i)
            continue;
        end

        if numel(app.SimPaths) < i || isempty(app.SimPaths{i}) || size(app.SimPaths{i},2) ~= 2
            needReplan(i) = true;
            continue;
        end

        pth = app.SimPaths{i};

        idx = app.PathIdx(i);
        if ~isfinite(idx) || idx < 1, idx = 1; end
        idx = round(idx);
        idx = max(1, min(idx, size(pth,1)));
        app.PathIdx(i) = idx;

        % path idx current cell ile uyuşmuyorsa path içinde current'ı yakala
        if ~isequal(pth(idx,:), curPos(i,:))
            kf = find(pth(:,1)==curPos(i,1) & pth(:,2)==curPos(i,2), 1, 'last');
            if isempty(kf)
                needReplan(i)  = true;
                forceReplan(i) = true;  % kritik: path’ten tamamen koptu
                continue;
            end
            idx = kf;
            app.PathIdx(i) = idx;
        end

        if idx >= size(pth,1)
            needReplan(i) = true;
            continue;
        end

        cand = pth(idx+1,:);

        % bounds / obstacle check
        if cand(1)<1 || cand(1)>app.GridSize || cand(2)<1 || cand(2)>app.GridSize
            needReplan(i)  = true;
            forceReplan(i) = true;
            continue;
        end
        if app.MapData(cand(1), cand(2)) == 1
            needReplan(i)  = true;
            forceReplan(i) = true;
            continue;
        end

        nextPos(i,:)   = cand;
        wantsMove(i)   = ~isequal(cand, curPos(i,:));
    end

    % ===================== PRE-REPLAN (MODE'A GÖRE) =====================
    % Dinamik: daha agresif, Öncelik: sadece gerçekten gerekiyorsa veya çok beklediyse.
    for i=1:K
        if atGoal(i), continue; end
        if ~needReplan(i), continue; end

        doTry = false;
        if strcmp(dl,'Dinamik Rerouting')
            doTry = forceReplan(i) || (app.WaitCount(i) >= 2);
        elseif strcmp(dl,'Öncelik Bazlı')
            doTry = forceReplan(i) || (app.WaitCount(i) >= 4);
        else
            doTry = false;
        end

        if ~doTry
            continue;
        end

        % diğer robotları statik engel gibi koy
        blockMask = (app.MapData==1);
        for jj=1:K
            if jj==i, continue; end
            blockMask(curPos(jj,1), curPos(jj,2)) = true;
        end
        blockMask(curPos(i,1), curPos(i,2)) = false;
        blockMask(goalPos(i,1), goalPos(i,2)) = false;

        newPath = replanAvoiding(app, curPos(i,:), goalPos(i,:), blockMask, app.PathAlgo);
        if ~isempty(newPath) && size(newPath,1) >= 2
            app.SimPaths{i} = newPath;
            app.PathIdx(i)  = 1;

            % yeni next’i hemen üret
            cand = newPath(2,:);
            if app.MapData(cand(1),cand(2)) == 0
                nextPos(i,:) = cand;
                wantsMove(i) = true;
                needReplan(i)= false;
            end

            app.WaitCount(i) = 0; % replan yaptı, bekleme sayacını sıfırla
        else
            % başarısız replan -> bu tick beklesin
            wantsMove(i) = false;
            nextPos(i,:) = curPos(i,:);
            app.WaitCount(i) = app.WaitCount(i) + 1;
        end
    end

    % ===================== MODE ORDER =====================
    % Temel sıra (sonra forced-yield en başa çekilecek)
    switch dl
        case 'Bekle ve Dene'
            orderBase = 1:K;

        case 'Öncelik Bazlı'
            % sabit öncelik önce, eşitte çok bekleyen önce
            M = [app.Priority(:) -app.WaitCount(:) (1:K)'];
            [~, orderBase] = sortrows(M, [1 2 3]);

        case 'Dinamik Rerouting'
            % çok bekleyen önce, eşitte öncelik önce
            M = [-app.WaitCount(:) app.Priority(:) (1:K)'];
            [~, orderBase] = sortrows(M, [1 2 3]);

        otherwise
            orderBase = 1:K;
    end

    % ===================== DEADLOCK LISTS (THIS TICK) =====================
    deadCells = zeros(0,2);
    deadPairs = zeros(0,4);

    % ===================== OCCUPANCY MAP =====================
    curOwner = zeros(app.GridSize, app.GridSize, 'int16');
    for ii=1:K
        curOwner(curPos(ii,1), curPos(ii,2)) = int16(ii);
    end

    handledSwap  = false(1,K);
    forcedYield  = false(1,K);  % bu tick mutlaka önce işlensin
    didYieldMove = false(1,K);  % move sonrası replan tetiklesin

    % ===================== (1) SWAP (HEAD-ON) RESOLUTION =====================
    for ii=1:K
        if ~wantsMove(ii) || handledSwap(ii), continue; end

        tgt = nextPos(ii,:);
        j   = double(curOwner(tgt(1), tgt(2))); % hedef hücrede kim var?

        if j <= 0 || j == ii, continue; end
        if ~wantsMove(j) || handledSwap(j), continue; end

        % head-on swap mı?
        if ~isequal(nextPos(j,:), curPos(ii,:))
            continue;
        end

        a = ii; b = j;
        handledSwap([a b]) = true;

        deadPairs(end+1,:) = [curPos(a,:) curPos(b,:)]; %#ok<AGROW>

        if strcmp(dl,'Bekle ve Dene')
            wantsMove([a b])   = false;
            nextPos([a b],:)   = curPos([a b],:);
            app.WaitCount([a b]) = app.WaitCount([a b]) + 1;
            continue;
        end

        % winner/loser seçimi:
        [win, lose] = pickSwapWinnerLoser(a,b);

        % loser yol versin: önce side, yoksa back
        [okS, ycell] = tryYieldSide(lose, win);
        if ~okS
            [okB, ycell] = tryYieldBack(lose, win);
            okS = okB;
        end

        if okS
            % winner düz devam (loser’in current’ına), loser yana/geri
            nextPos(win,:)  = curPos(lose,:); wantsMove(win)  = true;
            nextPos(lose,:) = ycell;          wantsMove(lose) = true;

            forcedYield(lose)  = true;   % önce yer ayırsın
            didYieldMove(lose) = true;

            app.WaitCount(lose) = app.WaitCount(lose) + 1;
        else
            % side/back yok -> dinamikte reroute dene
            if strcmp(dl,'Dinamik Rerouting')
                okR = tryRerouteWithExtraBlock(lose, curPos(win,:));
                if okR
                    wantsMove(win)  = false; nextPos(win,:)  = curPos(win,:);
                    wantsMove(lose) = true;  % replan sonrası setlenmiş olabilir
                    forcedYield(lose)  = true;
                    didYieldMove(lose) = true;
                    app.WaitCount(win) = app.WaitCount(win) + 1;
                    continue;
                end
            end

            % hiçbiri olmadı -> ikisi de bekle
            wantsMove([a b])   = false;
            nextPos([a b],:)   = curPos([a b],:);
            app.WaitCount([a b]) = app.WaitCount([a b]) + 1;
        end
    end

    % ===================== (2) GOAL-BLOCKER FIX (BITEN ROBOT YOLU TIKIYORSA) =====================
    % Not: Önce geçen robot için reroute dene; yoksa goal'daki çekilsin.
    if ~strcmp(dl,'Bekle ve Dene')
        for i=1:K
            if ~wantsMove(i) || atGoal(i), continue; end
            tgt = nextPos(i,:);
            occ = double(curOwner(tgt(1),tgt(2)));
            if occ<=0 || occ==i, continue; end
            if ~atGoal(occ), continue; end

            % 1) Önce i için occ hücresini engel sayıp reroute dene
            okPass = false;
            if strcmp(dl,'Dinamik Rerouting') || (strcmp(dl,'Öncelik Bazlı') && app.WaitCount(i) >= 2)
                okPass = tryRerouteWithExtraBlock(i, curPos(occ,:));
            end

            if okPass
                % i artık başka yere gidebilir, tamam
                continue;
            end

            % 2) Geçiş yoksa: goal'daki robot çekilsin (side/back)
            [okS, ycell] = tryYieldSide(occ, i);
            if ~okS
                [okB, ycell] = tryYieldBack(occ, i);
                okS = okB;
            end
            if okS
                nextPos(occ,:)   = ycell;
                wantsMove(occ)   = true;
                forcedYield(occ) = true;
                didYieldMove(occ)= true;
                app.WaitCount(occ) = app.WaitCount(occ) + 1;
            end
        end
    end

    % ===================== (3) FINAL ORDER (FORCED YIELD FIRST) =====================
    fy = find(forcedYield);
    order = [fy(:).' orderBase(:).'];
    order = unique(order,'stable');

    % ===================== (4) RESERVATION + DEPENDENCIES =====================
    allowed  = false(1,K);
    reserved = false(app.GridSize, app.GridSize);
    dependsOn = zeros(1,K,'int16');

    for kk=1:numel(order)
        i = order(kk);

        if ~wantsMove(i), continue; end
        tgt = nextPos(i,:);

        % hedef zaten rezerve mi?
        if reserved(tgt(1), tgt(2))
            deadCells = [deadCells; curPos(i,:)]; %#ok<AGROW>
            wantsMove(i)=false; nextPos(i,:)=curPos(i,:);
            app.WaitCount(i)=app.WaitCount(i)+1;
            continue;
        end

        % hedef hücrede şu an biri var mı?
        occ = double(curOwner(tgt(1), tgt(2)));

        if occ > 0 && occ ~= i
            if strcmp(dl,'Bekle ve Dene')
                deadCells = [deadCells; curPos(i,:)]; %#ok<AGROW>
                deadPairs(end+1,:) = [curPos(i,:) curPos(occ,:)]; %#ok<AGROW>
                wantsMove(i)=false; nextPos(i,:)=curPos(i,:);
                app.WaitCount(i)=app.WaitCount(i)+1;
                continue;
            end

            occWillVacate = wantsMove(occ) && ~isequal(nextPos(occ,:), curPos(occ,:));

            if ~occWillVacate
                % Dinamik: beklediyse reroute dene
                if strcmp(dl,'Dinamik Rerouting') && app.WaitCount(i) >= 2
                    okR = tryRerouteWithExtraBlock(i, curPos(occ,:));
                    if okR
                        % i reroute ettiyse, bu tick tekrar denemiyoruz
                        wantsMove(i) = true; % replan ayarladı
                        tgt = nextPos(i,:);
                        if reserved(tgt(1),tgt(2))
                            deadCells = [deadCells; curPos(i,:)]; %#ok<AGROW>
                            wantsMove(i)=false; nextPos(i,:)=curPos(i,:);
                            app.WaitCount(i)=app.WaitCount(i)+1;
                            continue;
                        end
                        occ2 = double(curOwner(tgt(1),tgt(2)));
                        if occ2>0 && occ2~=i
                            deadCells = [deadCells; curPos(i,:)]; %#ok<AGROW>
                            deadPairs(end+1,:) = [curPos(i,:) curPos(occ2,:)]; %#ok<AGROW>
                            wantsMove(i)=false; nextPos(i,:)=curPos(i,:);
                            app.WaitCount(i)=app.WaitCount(i)+1;
                            continue;
                        end
                    end
                end

                deadCells = [deadCells; curPos(i,:)]; %#ok<AGROW>
                deadPairs(end+1,:) = [curPos(i,:) curPos(occ,:)]; %#ok<AGROW>
                wantsMove(i)=false; nextPos(i,:)=curPos(i,:);
                app.WaitCount(i)=app.WaitCount(i)+1;
                continue;
            else
                dependsOn(i) = int16(occ);
            end
        end

        % hedef boş/rezerve değil -> rezerve et
        reserved(tgt(1), tgt(2)) = true;
        allowed(i) = true;
    end

    % ===================== (5) DEPENDENCY CLEANUP =====================
    changed = true;
    while changed
        changed = false;
        for i = find(allowed)
            j = double(dependsOn(i));
            if j > 0 && ~allowed(j)
                allowed(i) = false;
                reserved(nextPos(i,1), nextPos(i,2)) = false;
                wantsMove(i)=false; nextPos(i,:)=curPos(i,:);
                app.WaitCount(i)=app.WaitCount(i)+1;
                changed = true;
            end
        end
    end

    % ===================== DEADLOCK COUNTER (COMPONENT) =====================
    keysNow = app.deadlockComponentKeys(deadPairs, deadCells);
    if isempty(keysNow)
        app.DeadlockActive   = false;
        app.LastDeadlockKeys = strings(0,1);
    else
        app.DeadlockActive = true;
        newKeys = setdiff(keysNow, app.LastDeadlockKeys);
        if ~isempty(newKeys)
            app.TotalDeadlocks = app.TotalDeadlocks + numel(newKeys);
        end
        app.LastDeadlockKeys = keysNow;
    end

    % ===================== (6) MOVE =====================
    movedAny = false;

    newPos = curPos;
    for i=find(allowed)
        if isequal(nextPos(i,:), curPos(i,:)), continue; end
        r = nextPos(i,1); c = nextPos(i,2);
        if app.MapData(r,c) == 1, continue; end

        newPos(i,:) = nextPos(i,:);

        app.PathIdx(i) = app.PathIdx(i) + 1;
        app.HistoryPaths{i} = [app.HistoryPaths{i}; nextPos(i,:)]; %#ok<AGROW>

        app.WaitCount(i) = 0;
        movedAny = true;

        if i<=numel(app.RobotHandles) && isgraphics(app.RobotHandles(i))
            app.RobotHandles(i).XData = c;
            app.RobotHandles(i).YData = r;
        end
        if i<=numel(app.RobotTextHandles) && isgraphics(app.RobotTextHandles(i))
            app.RobotTextHandles(i).Position(1) = c+0.2;
            app.RobotTextHandles(i).Position(2) = r;
        end
    end

    % ===================== (7) YIELD-MOVE AFTER-REPLAN (KRİTİK) =====================
    % Yol veren robot path’ten kopar -> hemen replan yap, yoksa saçmalıyor.
    if ~strcmp(dl,'Bekle ve Dene')
        for i=1:K
            if ~didYieldMove(i), continue; end
            % gerçekten hareket etti mi?
            if isequal(newPos(i,:), curPos(i,:)), continue; end
            if isequal(newPos(i,:), goalPos(i,:)), continue; end

            blockMask = (app.MapData==1);
            for jj=1:K
                if jj==i, continue; end
                blockMask(newPos(jj,1), newPos(jj,2)) = true;
            end
            blockMask(newPos(i,1), newPos(i,2)) = false;
            blockMask(goalPos(i,1), goalPos(i,2)) = false;

            newPath = replanAvoiding(app, newPos(i,:), goalPos(i,:), blockMask, app.PathAlgo);
            if ~isempty(newPath) && size(newPath,1) >= 2
                app.SimPaths{i} = newPath;
                app.PathIdx(i)  = 1;
            end
        end
    end

    % ===================== STALL =====================
    if movedAny
        app.NoMoveTicks = 0;
    else
        app.NoMoveTicks = app.NoMoveTicks + 1;
    end

    if app.NoMoveTicks >= app.MaxNoMoveTicks
        app.IsRunning = false;
        stopAndDeleteTimer();
        try updateHud(app); catch, end
        plotSpeedWheelGraphsInApp(app, app.WheelBase, app.CellSize);
        return;
    end

    % ===================== OVERLAY ACCUMULATE + DRAW =====================
    if ~isempty(deadCells)
        app.DeadlockCellsAcc = unique([app.DeadlockCellsAcc; deadCells], 'rows');
    end
    if ~isempty(deadPairs)
        deadPairsN = app.normalizePairs(deadPairs);
        app.DeadlockPairsAcc = unique([app.DeadlockPairsAcc; deadPairsN], 'rows');
    end

    drawDeadlocksOverlay(app, app.DeadlockPairsAcc, app.DeadlockCellsAcc);
    drawTraveledPaths(app, K);

    % ===================== FINISH =====================
    finishIfAllGoal();

    try updateHud(app); catch, end
    drawnow limitrate nocallbacks

    % ==========================================================
    % ===================== NESTED HELPERS =====================
    % ==========================================================

    function [winner, loser] = pickSwapWinnerLoser(a,b)
        % KURAL:
        % - biri goal’da ise: goal’da OLMAYAN kazansın (geçsin), goal’daki yol versin.
        % - değilse: sabit Priority düşük olan kazansın.
        if atGoal(a) && ~atGoal(b)
            winner = b; loser = a; return;
        elseif atGoal(b) && ~atGoal(a)
            winner = a; loser = b; return;
        end

        pa = app.Priority(a);
        pb = app.Priority(b);

        if pa < pb
            winner = a; loser = b;
        elseif pb < pa
            winner = b; loser = a;
        else
            % eşitte: çok bekleyen kazansın
            wa = app.WaitCount(a); wb = app.WaitCount(b);
            if wa > wb
                winner = a; loser = b;
            elseif wb > wa
                winner = b; loser = a;
            else
                % tamamen eşit -> index küçük kazansın
                if a < b, winner=a; loser=b; else, winner=b; loser=a; end
            end
        end
    end

    function [ok, ycell] = tryYieldSide(loseIdx, winIdx)
        ok = false;
        ycell = curPos(loseIdx,:);

        dirs = [1 0; -1 0; 0 1; 0 -1];

        % current occupancy (bu tick’in anlık curPos’ları)
        occMap = false(app.GridSize, app.GridSize);
        for uu=1:K
            occMap(curPos(uu,1), curPos(uu,2)) = true;
        end
        % kendi hücresini boş say
        occMap(curPos(loseIdx,1), curPos(loseIdx,2)) = false;

        % hedefler: kimsenin "şu tick" target’ına girmesin (çatışma azaltır)
        tgtList = nextPos(wantsMove,:);
        forbidTargets = tgtList;

        forbid1 = curPos(winIdx,:);
        forbid2 = curPos(loseIdx,:);
        forbid3 = nextPos(winIdx,:); % winner’ın hedefi (loser current) ile çakışma olmasın

        r0 = curPos(loseIdx,1);
        c0 = curPos(loseIdx,2);

        for kdir=1:4
            r = r0 + dirs(kdir,1);
            c = c0 + dirs(kdir,2);

            if r<1||r>app.GridSize||c<1||c>app.GridSize, continue; end
            if app.MapData(r,c)==1, continue; end
            if occMap(r,c), continue; end

            cand = [r c];

            if isequal(cand, forbid1) || isequal(cand, forbid2) || isequal(cand, forbid3)
                continue;
            end

            % kimsenin target’ıyla çakışmasın
            if ~isempty(forbidTargets)
                if any(forbidTargets(:,1)==r & forbidTargets(:,2)==c)
                    continue;
                end
            end

            ok = true;
            ycell = cand;
            return;
        end
    end

    function [ok, ycell] = tryYieldBack(loseIdx, winIdx)
        ok = false;
        ycell = curPos(loseIdx,:);

        r0 = curPos(loseIdx,1);
        c0 = curPos(loseIdx,2);

        dr = r0 - curPos(winIdx,1);
        dc = c0 - curPos(winIdx,2);
        dr = sign(dr); dc = sign(dc);

        if dr==0 && dc==0, return; end

        r = r0 + dr;
        c = c0 + dc;

        if r<1||r>app.GridSize||c<1||c>app.GridSize, return; end
        if app.MapData(r,c)==1, return; end

        % occupancy
        for uu=1:K
            if uu==loseIdx, continue; end
            if curPos(uu,1)==r && curPos(uu,2)==c
                return;
            end
        end

        % kimsenin target’ına girme
        tgtList = nextPos(wantsMove,:);
        if ~isempty(tgtList)
            if any(tgtList(:,1)==r & tgtList(:,2)==c)
                return;
            end
        end

        if isequal([r c], curPos(winIdx,:)), return; end

        ok = true;
        ycell = [r c];
    end

    function ok = tryRerouteWithExtraBlock(who, extraBlockCell)
        % who için: extraBlockCell hücresini de engel sayarak reroute dene
        ok = false;
        if who < 1 || who > K, return; end
        if isequal(curPos(who,:), goalPos(who,:)), return; end

        startRC = curPos(who,:);
        goalRC  = goalPos(who,:);

        blockMask = (app.MapData==1);
        for jj=1:K
            if jj==who, continue; end
            blockMask(curPos(jj,1), curPos(jj,2)) = true;
        end
        if ~isempty(extraBlockCell)
            blockMask(extraBlockCell(1), extraBlockCell(2)) = true;
        end

        blockMask(startRC(1),startRC(2)) = false;
        blockMask(goalRC(1),goalRC(2))   = false;

        newPath = replanAvoiding(app, startRC, goalRC, blockMask, app.PathAlgo);
        if ~isempty(newPath) && size(newPath,1) >= 2
            app.SimPaths{who} = newPath;
            app.PathIdx(who)  = 1;
            nextPos(who,:)    = newPath(2,:);
            wantsMove(who)    = true;
            ok = true;
        end
    end

    function finishIfAllGoal()
        atGoal2 = false(1,K);
        for ii=1:K
            if isempty(app.HistoryPaths{ii})
                atGoal2(ii) = false;
            else
                atGoal2(ii) = isequal(app.HistoryPaths{ii}(end,:), goalPos(ii,:));
            end
        end
        if all(atGoal2)
            app.IsRunning = false;
            stopAndDeleteTimer();
            try updateHud(app); catch, end
            plotSpeedWheelGraphsInApp(app, app.WheelBase, app.CellSize);
        end
    end

    function stopAndDeleteTimer()
        if ~isempty(app.SimTimer) && isvalid(app.SimTimer)
            stop(app.SimTimer);
            delete(app.SimTimer);
        end
        app.SimTimer = [];
    end
end


function clearOverlays(app)
    % Timer/sim koşuyorsa kesin durdur (en kritik fix)
    stopSimulation(app);

    % Planlanan çizgiler
    if ~isempty(app.PathHandles)
        for i=1:numel(app.PathHandles)
            h = app.PathHandles{i};
            if ~isempty(h) && isgraphics(h), delete(h); end
        end
    end
    app.PathHandles = {};

    % Gidilen yol
    if ~isempty(app.TraveledPathHandles)
        for i=1:numel(app.TraveledPathHandles)
            h = app.TraveledPathHandles{i};
            if ~isempty(h) && isgraphics(h), delete(h); end
        end
    end
    app.TraveledPathHandles = {};

    % Deadlock işaretleri
    clearDeadlockMarks(app);

    app.DeadlockCellsAcc = zeros(0,2);
    app.DeadlockPairsAcc = zeros(0,4);


    % Robot / hedef / text
    if ~isempty(app.RobotHandles)
        for i=1:numel(app.RobotHandles)
            if isgraphics(app.RobotHandles(i)), delete(app.RobotHandles(i)); end
        end
    end
    if ~isempty(app.GoalHandles)
        for i=1:numel(app.GoalHandles)
            if isgraphics(app.GoalHandles(i)), delete(app.GoalHandles(i)); end
        end
    end
    if ~isempty(app.RobotTextHandles)
        for i=1:numel(app.RobotTextHandles)
            if isgraphics(app.RobotTextHandles(i)), delete(app.RobotTextHandles(i)); end
        end
    end
    app.RobotHandles = gobjects(0);
    app.GoalHandles  = gobjects(0);
    app.RobotTextHandles = gobjects(0);
end


%deadlock 

function clearDeadlockMarks(app)
    hs = app.DeadlockMarkHandles;

    if isempty(hs)
        app.DeadlockMarkHandles = gobjects(0);
        return;
    end

    % hs tek handle da olabilir, vektör de olabilir
    for k = 1:numel(hs)
        if isgraphics(hs(k))
            delete(hs(k));
        end
    end

    app.DeadlockMarkHandles = gobjects(0);
end


function markDeadlockCells(app, cellsRC)
    % cellsRC: Mx2 [r c]
    clearDeadlockMarks(app);
    if isempty(cellsRC), return; end

    ax = app.UIAxes;
    hold(ax,'on');

    hs = gobjects(size(cellsRC,1),1);

    for i=1:size(cellsRC,1)
        r = cellsRC(i,1);
        c = cellsRC(i,2);

        hs(i) = rectangle(ax, ...
            'Position',[c-0.5 r-0.5 1 1], ...
            'Curvature',[1 1], ...
            'EdgeColor','r', ...
            'LineWidth',2.8);
    end

    % Checkbox kapalıysa gizle (silme değil)
    if ~app.arpmanoktalarCheckBox.Value
        for i=1:numel(hs)
            if isgraphics(hs(i)), hs(i).Visible = 'off'; end
        end
    end

    hold(ax,'off');
    app.DeadlockMarkHandles = hs;
end


function markDeadlockPair(app, aRC, bRC)
    % aRC,bRC: [r c] -> iki robotu tek kırmızı daire içine al (kesin kapsasın)
    clearDeadlockMarks(app);

    ax = app.UIAxes;
    hold(ax,'on');

    r1 = aRC(1); c1 = aRC(2);
    r2 = bRC(1); c2 = bRC(2);

    % bounding box + padding
    pad = 0.9;
    minC = min(c1,c2) - pad;
    maxC = max(c1,c2) + pad;
    minR = min(r1,r2) - pad;
    maxR = max(r1,r2) + pad;

    w = maxC - minC;
    h = maxR - minR;
    d = max(w,h);           % daire çapı

    cx = (minC + maxC)/2;
    cy = (minR + maxR)/2;

    pos = [cx - d/2, cy - d/2, d, d];

    hdl = rectangle(ax, ...
        'Position', pos, ...
        'Curvature',[1 1], ...
        'EdgeColor','r', ...
        'LineWidth',3.2);

    if ~app.arpmanoktalarCheckBox.Value
        hdl.Visible = 'off';
    end

    hold(ax,'off');
    app.DeadlockMarkHandles = hdl;
end


function stopSimulation(app)
    app.IsRunning = false;

    if ~isempty(app.SimTimer) && isvalid(app.SimTimer)
        stop(app.SimTimer);
        delete(app.SimTimer);
    end
    app.SimTimer = [];

    % sim state reset
    app.PathIdx = [];
    app.HasSimState = false;
end

function newPath = replanAvoiding(app, startRC, goalRC, blockMask, algoOverride)
    % blockMask: true=blocked, false=free (engel + robotlar)
    % algoOverride: 'AStar' | 'Dijkstra' | 'HybridAStar'  (opsiyonel)

    if nargin < 5 || isempty(algoOverride)
        algoOverride = app.PathAlgo;
    end

    % --- eski state'i sakla (SADECE Map değil, graph cache de) ---
    oldMap      = app.MapData;
    oldFreeId   = app.FreeIdMap;
    oldFreeR    = app.FreeR;
    oldFreeC    = app.FreeC;
    oldFreeG    = app.FreeGraph;

    c = onCleanup(@() restoreState(app, oldMap, oldFreeId, oldFreeR, oldFreeC, oldFreeG));

    % --- geçici map = blockMask ---
    app.MapData = double(blockMask);

    switch algoOverride
        case 'AStar'
            % Planlama A*'da allowDiag=true kullanıyorsun -> aynı davranış kalsın
            newPath = astarPath4or8(app, startRC, goalRC, true);

        case 'Dijkstra'
            % Dijkstra için graph'ı bu geçici map'e göre kur
            buildFreeGraph(app);
            newPath = dijkstraPath(app, startRC, goalRC);

        case 'HybridAStar'
            newPath = hybridTurnCostAStar(app, startRC, goalRC);

        otherwise
            newPath = astarPath4or8(app, startRC, goalRC, true);
    end
end

function restoreState(app, mapCopy, freeId, freeR, freeC, freeG)
    if isempty(app) || ~isvalid(app), return; end
    try
        app.MapData    = mapCopy;
        app.FreeIdMap  = freeId;
        app.FreeR      = freeR;
        app.FreeC      = freeC;
        app.FreeGraph  = freeG;
    catch
        % app kapanırken sessiz geç
    end
end


function setMapSafe(app, mapCopy)
    if isempty(app) || ~isvalid(app)
        return;
    end
    try
        app.MapData = mapCopy;
    catch
        % app kapanırken sessiz geç
    end
end


function drawDeadlocksOverlay(app, pairs, singles)
    % pairs  : Nx4 [r1 c1 r2 c2]
    % singles: Mx2 [r c]
    %
    % Amaç:
    % - Pairs ile oluşan connected component'leri TEK daireyle kapsa
    % - Singles (tek başına stuck olanlar) daireyle gösterilsin
    % - Her tick delete+recreate yapma -> varsa handle'ları UPDATE et (donma azalır)

    persistent lastPairs lastSingles

    % checkbox görünürlüğü her çağrıda güncellensin
    function applyVisibility(hs)
        if isempty(hs), return; end
        if app.arpmanoktalarCheckBox.Value
            vis = 'on';
        else
            vis = 'off';
        end
        for kk=1:numel(hs)
            if isgraphics(hs(kk)), hs(kk).Visible = vis; end
        end
    end

    % Hiçbir şey yoksa temizle
    if (isempty(pairs) || size(pairs,1)==0) && (isempty(singles) || size(singles,1)==0)
        clearDeadlockMarks(app);
        return;
    end

    % Eğer data değişmediyse sadece visible güncelle ve çık (hız)
    if isequal(pairs, lastPairs) && isequal(singles, lastSingles)
        applyVisibility(app.DeadlockMarkHandles);
        return;
    end
    lastPairs   = pairs;
    lastSingles = singles;

    % --------- tüm noktaları topla (pairs uçları + singles) ---------
    nPairs = 0;
    pts = zeros(0,2);
    if ~isempty(pairs)
        nPairs = size(pairs,1);
        pts = [pairs(:,1:2); pairs(:,3:4)];
    end
    if ~isempty(singles)
        pts = [pts; singles];
    end

    if isempty(pts)
        clearDeadlockMarks(app);
        return;
    end

    [U, ~, idxU] = unique(pts, 'rows');   % U = Mx2
    M = size(U,1);

    % Bu node "single kaynaklı mı?"
    isSingleNode = false(M,1);
    if ~isempty(singles)
        nPairPts = 2*nPairs;
        idxSingles = idxU(nPairPts+1:end);
        isSingleNode(idxSingles) = true;
    end

    % --------- graph edges (pairs) ---------
    if nPairs > 0
        ia = idxU(1:nPairs);
        ib = idxU(nPairs+1:2*nPairs);
        keep = (ia ~= ib);
        ia = ia(keep); ib = ib(keep);
    else
        ia = []; ib = [];
    end

    if ~isempty(ia)
        G = graph(ia, ib, [], M);
        comp = conncomp(G);   % 1..C
    else
        % Edge yoksa herkes tek component gibi davranır (ama tek tek çizeceğiz)
        comp = (1:M);
    end

    C = max(comp);

    % --------- çizilecek daire "pos" listesi üret ---------
    posList = zeros(0,4); % [x y w h]
    pad = 0.90;

    for cc = 1:C
        nodes = find(comp == cc);

        % Çizim kuralı:
        % - component >=2 ise çiz
        % - component 1 ise SADECE single ise çiz (tek stuck robot)
        if numel(nodes) >= 2
            okDraw = true;
        else
            okDraw = isSingleNode(nodes);
        end
        if ~okDraw, continue; end

        ptsC = U(nodes,:);    % [r c]
        rs = ptsC(:,1); cs = ptsC(:,2);

        minC = min(cs) - pad; maxC = max(cs) + pad;
        minR = min(rs) - pad; maxR = max(rs) + pad;

        w = maxC - minC;
        h = maxR - minR;
        d = max(w,h);             % daire için kare bounding

        cx = (minC + maxC)/2;
        cy = (minR + maxR)/2;

        pos = [cx - d/2, cy - d/2, d, d];  % x=c, y=r
        posList(end+1,:) = pos; %#ok<AGROW>
    end

    % Hiç çizilecek şey kalmadıysa temizle
    if isempty(posList)
        clearDeadlockMarks(app);
        return;
    end

    ax = app.UIAxes;
    hold(ax,'on');

    % --------- handle'ları UPDATE et / gerekiyorsa recreate ---------
    hs = app.DeadlockMarkHandles;

    needRecreate = isempty(hs) || ~all(isgraphics(hs)) || numel(hs) ~= size(posList,1);

    if needRecreate
        clearDeadlockMarks(app);
        hs = gobjects(size(posList,1),1);
        for k=1:size(posList,1)
            hs(k) = rectangle(ax, ...
                'Position', posList(k,:), ...
                'Curvature',[1 1], ...
                'EdgeColor','r', ...
                'LineWidth',3.2);
        end
        app.DeadlockMarkHandles = hs;
    else
        for k=1:size(posList,1)
            hs(k).Position = posList(k,:);
        end
    end

    % Daire “gerçek daire” gibi dursun diye (ölçek eşit)
    axis(ax,'image'); % zaten vardı ama burada garanti

    applyVisibility(app.DeadlockMarkHandles);

    hold(ax,'off');
end


function drawTraveledPaths(app, K)
    % Gidilen yolu (HistoryPaths) solid çiz.
    % Planlanan yol (PathHandles / PlannedPaths) asla değişmez.

    if nargin < 2 || isempty(K)
        K = numel(app.RobotData);
    end
    if K <= 0, return; end

    % handle array güvenliği
    if isempty(app.TraveledPathHandles) || ~iscell(app.TraveledPathHandles) || numel(app.TraveledPathHandles) < K
        tmp = cell(1,K);
        if iscell(app.TraveledPathHandles)
            ncopy = min(numel(app.TraveledPathHandles), K);
            tmp(1:ncopy) = app.TraveledPathHandles(1:ncopy);
        end
        app.TraveledPathHandles = tmp;
    end

    ax = app.UIAxes;
    hold(ax,'on');

    for i = 1:K
        if numel(app.HistoryPaths) < i || isempty(app.HistoryPaths{i})
            continue;
        end

        path = app.HistoryPaths{i};     % Nx2 [r c]
        if size(path,2) ~= 2
            continue;
        end

        % tek nokta varsa çizgi yok
        if size(path,1) < 2
            continue;
        end

        % renk
        col = [1 1 1];
        if numel(app.RobotData) >= i && isfield(app.RobotData(i),'Color') && ~isempty(app.RobotData(i).Color)
            col = app.RobotData(i).Color;
        end

        h = app.TraveledPathHandles{i};
        if isempty(h) || ~isgraphics(h)
            % ilk kez oluştur
            h = plot(ax, path(:,2), path(:,1), '-', 'Color', col, 'LineWidth', 2.8);
            app.TraveledPathHandles{i} = h;
        else
            % sadece data güncelle
            h.XData = path(:,2);
            h.YData = path(:,1);
        end

        % checkbox görünürlüğü
        if app.GidilenYolCheckBox.Value
            h.Visible = 'on';
        else
            h.Visible = 'off';
        end
    end

    % robot/goal/text en üstte kalsın
    if ~isempty(app.RobotHandles),     try uistack(app.RobotHandles,'top'); end, end
    if ~isempty(app.GoalHandles),      try uistack(app.GoalHandles,'top'); end, end
    if ~isempty(app.RobotTextHandles), try uistack(app.RobotTextHandles,'top'); end, end

    hold(ax,'off');
end


function plotSpeedWheelGraphsInApp(app, wheelBase, cellSize)
    % wheelBase: metre (örn 0.3)
    % cellSize : metre (örn 1.0)  1 hücre kaç metre
    if nargin < 2 || isempty(wheelBase), wheelBase = 0.3; end
    if nargin < 3 || isempty(cellSize),  cellSize  = 1.0; end

    dt = app.TickPeriod;
    if isempty(dt) || ~isfinite(dt) || dt <= 0
        dt = 0.15;
    end

    % --- eski hız tablarını temizle ---
    ch = app.TabGroup.Children; % tabs
    for k = 1:numel(ch)
        if isprop(ch(k),'Tag') && strcmp(ch(k).Tag,'SpeedTab')
            delete(ch(k));
        end
    end

    K = numel(app.RobotData);

    firstCreatedTab = [];

    for i = 1:K
        if numel(app.HistoryPaths) < i || isempty(app.HistoryPaths{i})
            continue;
        end

        P = double(app.HistoryPaths{i});   % Nx2 [r c]
        n = size(P,1);
        if n < 2
            continue;
        end

        % Robot rengi (v ve w)
        col = [1 1 1];
        if numel(app.RobotData) >= i && isfield(app.RobotData(i),'Color') && ~isempty(app.RobotData(i).Color)
            col = app.RobotData(i).Color;
        end

        % Adım farkları (grid)
        dRC = diff(P,1,1);     % (n-1)x2
        dy  = dRC(:,1);
        dx  = dRC(:,2);

        % Mesafe (m)
        ds = cellSize * sqrt(dx.^2 + dy.^2);  % (n-1)x1

        % v(t)
        v = zeros(n,1);
        v(2:n) = ds ./ dt;

        % w(t)
        thetaSeg = atan2(dy, dx); % (n-1)x1
        wrap = @(a) atan2(sin(a), cos(a));

        w = zeros(n,1);
        if n >= 3
            dtheta = wrap(diff(thetaSeg));   % (n-2)x1
            w(3:n) = dtheta ./ dt;
        end

        % Teker hızları (differential drive)
        vL = v - (wheelBase/2) * w;
        vR = v + (wheelBase/2) * w;

        t = (0:n-1).' * dt;

        % --- Tab + layout + 3 uiaxes ---
        tab = uitab(app.TabGroup, 'Title', sprintf('R%d Hız', i));
        tab.Tag = 'SpeedTab';

        gl = uigridlayout(tab, [3 1]);
        gl.RowHeight = {'1x','1x','1x'};
        gl.ColumnWidth = {'1x'};
        gl.Padding = [8 8 8 8];

        ax1 = uiaxes(gl); ax1.Layout.Row = 1;
        ax2 = uiaxes(gl); ax2.Layout.Row = 2;
        ax3 = uiaxes(gl); ax3.Layout.Row = 3;

        % v(t)
        plot(ax1, t, v, '-', 'Color', col, 'LineWidth', 2);
        grid(ax1,'on');
        title(ax1, sprintf('Robot %d - Doğrusal Hız v(t) (m/s)', i));
        xlabel(ax1,'Zaman (s)'); ylabel(ax1,'Hız (m/s)');

        % w(t)
        plot(ax2, t, w, '-', 'Color', col, 'LineWidth', 2);
        grid(ax2,'on');
        title(ax2, sprintf('Robot %d - Açısal Hız w(t) (rad/s)', i));
        xlabel(ax2,'Zaman (s)'); ylabel(ax2,'Hız (rad/s)');

        % teker hızları
        plot(ax3, t, vL, 'r-', 'LineWidth', 2); hold(ax3,'on');
        plot(ax3, t, vR, 'b-', 'LineWidth', 2);
        grid(ax3,'on');
        title(ax3, sprintf('Robot %d - Tekerlek Hızları (m/s)', i));
        xlabel(ax3,'Zaman (s)'); ylabel(ax3,'Hız (m/s)');
        legend(ax3, 'Sol Tekerlek','Sağ Tekerlek','Location','best');
        hold(ax3,'off');

        if isempty(firstCreatedTab)
            firstCreatedTab = tab;
        end
    end

    % ilk oluşturulan hız tabına geç
    if ~isempty(firstCreatedTab) && isvalid(firstCreatedTab)
        app.TabGroup.SelectedTab = firstCreatedTab;
    end
end


function clearSpeedTabs(app)
    if isempty(app.TabGroup) || ~isvalid(app.TabGroup), return; end
    ch = app.TabGroup.Children;
    for k = 1:numel(ch)
        if isprop(ch(k),'Tag') && strcmp(ch(k).Tag,'SpeedTab')
            delete(ch(k));
        end
    end
end


    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RandomMapButton
        function RandomMapButtonPushed(app, event)
         
%random map butonu

%clean atmak için robotların yollarına
clearSpeedTabs(app);
clearOverlays(app);


app.SeedEditField.RoundFractionalValues = true;
app.SeedEditField.ValueDisplayFormat    = '%.0f';


    % reset
    app.RobotData     = struct('Start', {}, 'Goal', {}, 'Color', {});
    app.PlannedPaths  = {};
    app.OriginalPaths = {};
    app.HistoryPaths  = {};
    app.IsRandomMap   = true;

    % --- seed oku ---
    userVal = app.SeedEditField.Value;

    if isempty(userVal) || userVal == 0
        % kullanıcı 0 bıraktıysa: random seed üret ama kutuya yazma
        seed = randi(1e6);
    else
        % kullanıcı seed girdiyse: onu kullan (kutuda zaten kalır)
        seed = floor(userVal);
    end

    % --- map üret ---
    app.MainSeed = seed;
    app.MapData  = app.generateRandomMap(seed);

    % robot seed (tekrar üretilebilir olsun)
    app.RobotSeed = seed + 1;

    % graph cache
    buildFreeGraph(app);

    % çiz
    drawMap(app, sprintf('Random Map | Seed: %d', seed));


        end

        % Value changed function: SeedEditField
        function SeedEditFieldValueChanged(app, event)
            value = app.SeedEditField.Value;
            
%seed degeri


        end

        % Button pushed function: DarKoridorButton
        function DarKoridorButtonPushed(app, event)

%Dar koridor tuşu
    clearSpeedTabs(app);
    clearOverlays(app);


    app.RobotData = struct('Start', {}, 'Goal', {}, 'Color', {});
    app.PlannedPaths = {};
    app.OriginalPaths = {};
    app.HistoryPaths = {};
    app.IsRandomMap = false;

    N = app.GridSize;
    map = zeros(N,N);

    rectW = 4;
    gap = 2;
    startX = 3;

    for i=0:7
        x1 = startX + i*(rectW+gap);
        x2 = min(N, x1+rectW-1);
        map(1:24, x1:x2) = 1;
        map(27:N, x1:x2) = 1;
    end

    app.MapData = map;

    if app.SeedEditField.Value > 0
        app.RobotSeed = app.SeedEditField.Value;
    else
        app.RobotSeed = randi(1e6);
    end

    buildFreeGraph(app);
    drawMap(app, 'Dar Koridor');


        end

        % Button pushed function: RobotlarYerletirButton
        function RobotlarYerletirButtonPushed(app, event)


    % ROBOTLARI YERLEŞTİR (her basışta RANDOM - seed girilirse sabit)

    % Eski sim/overlay temizle (ışınlanma/donma fix)
    stopSimulation(app);
    clearOverlays(app);
    clearDeadlockMarks(app);
    clearSpeedTabs(app);

    if isempty(app.MapData)
        uialert(app.UIFigure,'Önce harita oluştur (Random Map / Dar Koridor).','Hata');
        return;
    end

    % --- Robot seed: her basışta random (seed girilirse deterministic) ---
    userSeed = app.SeedEditField.Value;
    if isempty(userSeed) || userSeed <= 0
        app.RobotSeed = randi(1e9);         % <-- HER BASIŞTA YENİ SEED
    else
        app.RobotSeed = floor(userSeed);    % <-- kullanıcı seed verdiyse aynı yerleşim
    end
    rng(app.RobotSeed);

    N = app.GridSize;

    freeMask = (app.MapData == 0);
    [freeR, freeC] = find(freeMask);
    if numel(freeR) < 2*app.NumRobots
        uialert(app.UIFigure,'Haritada yeterli boş hücre yok.','Hata');
        return;
    end
    freeList = [freeR freeC];

    % --- eski verileri sıfırla ---
    app.RobotData     = struct('Start', {}, 'Goal', {}, 'Color', {});
    app.PlannedPaths  = {};
    app.OriginalPaths = {};
    app.HistoryPaths  = {};
    app.PathIdx       = [];
    app.PathHandles   = {};
    app.TraveledPathHandles = {};
    app.WaitCount     = [];
    app.Priority      = [];

    % --- haritayı tekrar çiz ---
    if app.IsRandomMap && ~isempty(app.MainSeed)
        drawMap(app, sprintf('Robot Yerleştirme | MapSeed: %d | RobotSeed: %d', app.MainSeed, app.RobotSeed));
    else
        drawMap(app, sprintf('Robot Yerleştirme | RobotSeed: %d', app.RobotSeed));
    end

    % renkler (10 robota kadar)
    base = [ ...
        1 0 0;
        0 1 0;
        0 0.6 1;
        1 1 0;
        1 0 1;
        0 1 1;
        1 0.5 0;
        0.7 0.4 1;
        0.5 1 0;
        1 0.7 0.8];
    K = app.NumRobots;
    colors = base(mod(0:K-1, size(base,1)) + 1, :);

    % Near/Medium/Far dağılımı
    classes = strings(1,K);
    for i=1:K
        if i <= round(0.30*K), classes(i)="Near";
        elseif i <= round(0.70*K), classes(i)="Medium";
        else, classes(i)="Far";
        end
    end

    used = false(N,N);

    % handle alanlarını boyutla
    app.RobotHandles = gobjects(1,K);
    app.GoalHandles  = gobjects(1,K);
    app.RobotTextHandles = gobjects(1,K);

    hold(app.UIAxes,'on');

    for i=1:K
        % --- START seç ---
        ok = false;
        for tries=1:4000
            idx = randi(size(freeList,1));
            s = freeList(idx,:);
            if ~used(s(1),s(2))
                ok = true; break;
            end
        end
        if ~ok, continue; end
        used(s(1),s(2)) = true;

        % --- goal için BFS mesafe ---
        dist = bfsDist(app, s);

        if classes(i)=="Near"
            lo = 0.10*N; hi = 0.25*N;
        elseif classes(i)=="Medium"
            lo = 0.30*N; hi = 0.55*N;
        else
            lo = 0.60*N; hi = 1.20*N;
        end

        cand = find(isfinite(dist) & dist >= lo & dist <= hi);
        cand = cand(app.MapData(cand)==0);

        if ~isempty(cand)
            [cr,cc] = ind2sub([N N], cand);
            keep = ~arrayfun(@(r,c) used(r,c), cr, cc);
            cand = cand(keep);
        end

        % aralık yoksa: reachable herhangi bir goal
        if isempty(cand)
            cand = find(isfinite(dist) & dist > 0);
            cand = cand(app.MapData(cand)==0);
            if ~isempty(cand)
                [cr,cc] = ind2sub([N N], cand);
                keep = ~arrayfun(@(r,c) used(r,c), cr, cc);
                cand = cand(keep);
            end
        end

        if isempty(cand), continue; end

        gLin = cand(randi(numel(cand)));
        [gr,gc] = ind2sub([N N], gLin);
        g = [gr gc];
        used(gr,gc) = true;

        app.RobotData(i).Start = s;
        app.RobotData(i).Goal  = g;
        app.RobotData(i).Color = colors(i,:);

        col = colors(i,:);
        app.RobotHandles(i) = plot(app.UIAxes, s(2), s(1), 'o', ...
            'MarkerFaceColor', col, 'MarkerEdgeColor','k', 'MarkerSize', 8);
        app.GoalHandles(i) = plot(app.UIAxes, g(2), g(1), 'x', ...
            'Color', col, 'LineWidth', 2, 'MarkerSize', 10);
        app.RobotTextHandles(i) = text(app.UIAxes, s(2)+0.2, s(1), sprintf('R%d',i), ...
            'Color','w','FontWeight','bold','FontSize',8);
    end

    hold(app.UIAxes,'off');

    % sim öncelik/wait init
    K2 = numel(app.RobotData);
    app.WaitCount = zeros(1,K2);
    app.Priority  = 1:K2;

    title(app.UIAxes, sprintf('Robotlar Yerleşti | RobotSeed: %d', app.RobotSeed));


        end

        % Button pushed function: YolPlanlaButton
        function YolPlanlaButtonPushed(app, event)


    %Yol planlama tuşu

    if isempty(app.MapData) || isempty(app.RobotData)
        uialert(app.UIFigure,'Önce harita + robot yerleştir.','Hata');
        return;
    end

    % --- eski path çizimlerini 1 kere sil ---
    if ~isempty(app.PathHandles)
        for k=1:numel(app.PathHandles)
            if ~isempty(app.PathHandles{k}) && isgraphics(app.PathHandles{k})
                delete(app.PathHandles{k});
            end
        end
    end
    app.PathHandles = cell(1, numel(app.RobotData));

    % datası resetle
    app.PlannedPaths  = cell(1, numel(app.RobotData));   % PLAN (değişmez)
    app.SimPaths      = {};                               % SIM (start'ta kopyalanacak)
    app.HistoryPaths  = cell(1, numel(app.RobotData));

    % dropdown map
    uiVal = app.DropDown.Value;
switch uiVal
    case 'A* (Optimal)',              algo = 'AStar';
    case 'Dijkstra (Maliyet Odaklı)', algo = 'Dijkstra';
    case 'Hybrid A* (Araba Modu)',    algo = 'HybridAStar';
    otherwise,                        algo = 'AStar';
end

app.PathAlgo = algo;   % <<< KRİTİK: seçimi sakla


    % haritayı çiz
    drawMap(app, sprintf('Planlama | %s', algo));
    hold(app.UIAxes,'on');

    K = numel(app.RobotData);

    for i=1:K
        s = app.RobotData(i).Start;
        g = app.RobotData(i).Goal;

        if app.MapData(s(1),s(2))==1 || app.MapData(g(1),g(2))==1
            continue;
        end

        switch algo
            case 'AStar'
                path = astarPath4or8(app, s, g, true);
            case 'Dijkstra'
                path = dijkstraGrid(app, s, g);
            case 'HybridAStar'
                path = hybridTurnCostAStar(app, s, g);
        end

        app.PlannedPaths{i} = path;   % sadece plan
        app.HistoryPaths{i} = s;      % gidilen (simde büyür)

        col = app.RobotData(i).Color;

        if ~isempty(path)
            h = plot(app.UIAxes, path(:,2), path(:,1), '--', 'Color', col, 'LineWidth', 2.5);
            app.PathHandles{i} = h;

            if ~app.PlanlanmRotaCheckBox.Value
                h.Visible = 'off';
            end
        end
    end

    % robot + goal üstte kalsın
    if ~isempty(app.RobotHandles),     uistack(app.RobotHandles,'top'); end
    if ~isempty(app.GoalHandles),      uistack(app.GoalHandles,'top'); end
    if ~isempty(app.RobotTextHandles), uistack(app.RobotTextHandles,'top'); end

    hold(app.UIAxes,'off');
    title(app.UIAxes, sprintf('Planlama Tamam | %s', algo));


        end

        % Button pushed function: SimlasyonubalatButton
        function SimlasyonubalatButtonPushed(app, event)


    %Simulasyon başlatma butonu


    app.DeadlockCellsAcc = zeros(0,2);
    app.DeadlockPairsAcc = zeros(0,4);
    clearDeadlockMarks(app);
    clearSpeedTabs(app);


    if isempty(app.PlannedPaths) || all(cellfun(@isempty, app.PlannedPaths))
        uialert(app.UIFigure,'Önce Yol Planla.','Hata');
        return;
    end

    K = numel(app.RobotData);

    % --- SIM PATH = PLAN PATH KOPYASI (PLAN ASLA DEĞİŞMEYECEK) ---
    app.SimPaths = app.PlannedPaths;

    % --- HER SEFERİNDE RESET ---
    app.PathIdx = ones(1,K);
    app.HistoryPaths = cell(1,K);

    for i=1:K
        s = app.RobotData(i).Start;
        app.HistoryPaths{i} = s;

        % robotu görselde start'a geri koy
        if i<=numel(app.RobotHandles) && isgraphics(app.RobotHandles(i))
            app.RobotHandles(i).XData = s(2);
            app.RobotHandles(i).YData = s(1);
        end
        if i<=numel(app.RobotTextHandles) && isgraphics(app.RobotTextHandles(i))
            app.RobotTextHandles(i).Position(1) = s(2)+0.2;
            app.RobotTextHandles(i).Position(2) = s(1);
        end
    end

    app.IsRunning = true;
        
        app.IsRunning = true;
        
        % metrik reset
        app.TotalDeadlocks = 0;
        app.DeadlockActive = false;
        
        % süre reset (pause/resume sistemi)
        app.SimElapsed  = 0;
        app.SimStartTic = tic;
        
        app.StepCounter = 0;
        
        % eski HUD textleri varsa sil
        if ~isempty(app.HudTextHandles)
            for k=1:numel(app.HudTextHandles)
                if isgraphics(app.HudTextHandles(k)), delete(app.HudTextHandles(k)); end
            end
            app.HudTextHandles = gobjects(0);
        end
        
        initHud(app);
        updateHud(app);


    % timer varsa silip temiz başlat
    if ~isempty(app.SimTimer) && isvalid(app.SimTimer)
        stop(app.SimTimer);
        delete(app.SimTimer);
    end

    app.SimTimer = timer( ...
        'ExecutionMode','fixedRate', ...
        'Period', app.TickPeriod, ...
        'BusyMode','drop', ...
        'TimerFcn', @(~,~) simTick(app) );

    start(app.SimTimer);


        end

        % Button pushed function: SimlasyonuDurdurButton
        function SimlasyonuDurdurButtonPushed(app, event)
    

%simulasyon durdur butonu

    % Timer yoksa (hiç başlatılmadıysa) çık
    if isempty(app.SimTimer) || ~isvalid(app.SimTimer)
        return;
    end

    if strcmp(app.SimTimer.Running, 'on')
        % ===== PAUSE =====
        app.IsRunning = false;
        stop(app.SimTimer);

        % süreyi dondur (HUD için)
        if ~isempty(app.SimStartTic)
            app.SimElapsed = app.SimElapsed + toc(app.SimStartTic);
        end
        app.SimStartTic = [];

        app.SimlasyonuDurdurButton.Text = 'Devam Et';

        try updateHud(app); catch, end
    else
        % ===== RESUME =====
        app.IsRunning = true;

        % süreyi devam ettir
        app.SimStartTic = tic;

        start(app.SimTimer);

        app.SimlasyonuDurdurButton.Text = 'Simülasyonu Durdur';

        try updateHud(app); catch, end
    end


        end

        % Value changed function: PlanlanmRotaCheckBox
        function PlanlanmRotaCheckBoxValueChanged(app, event)
            value = app.PlanlanmRotaCheckBox.Value;


    if app.PlanlanmRotaCheckBox.Value
        onoff = 'on';
    else
        onoff = 'off';
    end

    if isempty(app.PathHandles)
        return;
    end

    for i = 1:numel(app.PathHandles)
        h = app.PathHandles{i};
        if ~isempty(h) && isgraphics(h)
            h.Visible = onoff;
        end
    end


%check box planlanan rotayı göstermesi için

        end

        % Value changed function: GidilenYolCheckBox
        function GidilenYolCheckBoxValueChanged(app, event)
            value = app.GidilenYolCheckBox.Value;
            %check box gidilen yolu göstermesi için

                if app.GidilenYolCheckBox.Value, onoff='on'; else, onoff='off'; end
    for i=1:numel(app.TraveledPathHandles)
        h = app.TraveledPathHandles{i};
        if ~isempty(h) && isgraphics(h), h.Visible = onoff; end
    end

        end

        % Value changed function: arpmanoktalarCheckBox
        function arpmanoktalarCheckBoxValueChanged(app, event)
            value = app.arpmanoktalarCheckBox.Value;
            %check box çarpışma noktalarını görmek için
            if app.arpmanoktalarCheckBox.Value, onoff='on'; else, onoff='off'; end
            for k=1:numel(app.DeadlockMarkHandles)
             h = app.DeadlockMarkHandles(k);
            if isgraphics(h), h.Visible = onoff; end
             end


        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)


%grafik 

        end

        % Size changed function: Tab
        function TabSizeChanged(app, event)
            position = app.Tab.Position;
            

%tab boyutuyla alakalı


        end

        % Button down function: Tab
        function TabButtonDown(app, event)
            
%tab butonuyla alakalı


        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
            value = app.DropDown.Value;
            
%drop box ile alakalı(yol bulma algoritması


        end

        % Drop down opening function: DropDown
        function DropDownOpening(app, event)


            %drop box ile alakalı(yol bulma algoritması
            
        end

        % Clicked callback: DropDown
        function DropDownClicked(app, event)
            item = event.InteractionInformation.Item;
            

            %drop box ile alakalı(yol bulma algoritması


        end

        % Value changed function: DropDown_2
        function DropDown_2ValueChanged(app, event)
            value = app.DropDown_2.Value;
            
            %drop box ile alakalı(Deadlock  algoritması)


        end

        % Drop down opening function: DropDown_2
        function DropDown_2Opening(app, event)
            

            %drop box ile alakalı(Deadlock  algoritması)


        end

        % Clicked callback: DropDown_2
        function DropDown_2Clicked(app, event)
            item = event.InteractionInformation.Item;
            

            %drop box ile alakalı(Deadlock  algoritması)

            
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {654, 654};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {273, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.MapAyarlarLabel = uilabel(app.LeftPanel);
            app.MapAyarlarLabel.Text = 'Map Ayarları';
            app.AlgoritmaAyarlarLabel = uilabel(app.LeftPanel);
            app.AlgoritmaAyarlarLabel.Text = 'Algoritma Ayarları';
            app.RandomMapButton = uibutton(app.LeftPanel, 'push');
            app.RandomMapButton.ButtonPushedFcn = createCallbackFcn(app, @RandomMapButtonPushed, true);
            app.RandomMapButton.Text = 'Random Map';
            app.SeedEditFieldLabel = uilabel(app.LeftPanel);
            app.SeedEditFieldLabel.Text = 'Seed';
            app.SeedEditField = uieditfield(app.LeftPanel, 'numeric');
            app.SeedEditField.ValueChangedFcn = createCallbackFcn(app, @SeedEditFieldValueChanged, true);
            app.SeedseipkullanmakiinseediyazpRandomMapetklaynzLabel = uilabel(app.LeftPanel);
            app.SeedseipkullanmakiinseediyazpRandomMapetklaynzLabel.Text = '(Seed seçip kullanmak için seed i yazıp Random Map e tıklayınız)';
            app.DropDown = uidropdown(app.LeftPanel);
            app.DropDown.Items = {'A* (Optimal)', 'Dijkstra (Maliyet Odaklı)', 'Hybrid A* (Araba Modu)'};
            app.DropDown.DropDownOpeningFcn = createCallbackFcn(app, @DropDownOpening, true);
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.ClickedFcn = createCallbackFcn(app, @DropDownClicked, true);
            app.DropDown.Value = 'A* (Optimal)';
            app.YolBulmaAlgoritmasLabel = uilabel(app.LeftPanel);
            app.YolBulmaAlgoritmasLabel.Text = 'Yol Bulma Algoritması';
            app.DeadlockAlgoritmasLabel = uilabel(app.LeftPanel);
            app.DeadlockAlgoritmasLabel.Text = 'Deadlock Algoritması';
            app.DropDown_2 = uidropdown(app.LeftPanel);
            app.DropDown_2.Items = {'Bekle ve Dene', 'Öncelik Bazlı', 'Dinamik Rerouting'};
            app.DropDown_2.DropDownOpeningFcn = createCallbackFcn(app, @DropDown_2Opening, true);
            app.DropDown_2.ValueChangedFcn = createCallbackFcn(app, @DropDown_2ValueChanged, true);
            app.DropDown_2.ClickedFcn = createCallbackFcn(app, @DropDown_2Clicked, true);
            app.DropDown_2.Value = 'Bekle ve Dene';
            app.SimlasyonuDurdurButton = uibutton(app.LeftPanel, 'push');
            app.SimlasyonuDurdurButton.ButtonPushedFcn = createCallbackFcn(app, @SimlasyonuDurdurButtonPushed, true);
            app.SimlasyonuDurdurButton.Text = 'Simülasyonu Durdur';
            app.DarKoridorButton = uibutton(app.LeftPanel, 'push');
            app.DarKoridorButton.ButtonPushedFcn = createCallbackFcn(app, @DarKoridorButtonPushed, true);
            app.DarKoridorButton.Text = 'Dar Koridor';
            app.RobotlarYerletirButton = uibutton(app.LeftPanel, 'push');
            app.RobotlarYerletirButton.ButtonPushedFcn = createCallbackFcn(app, @RobotlarYerletirButtonPushed, true);
            app.RobotlarYerletirButton.Text = 'Robotları Yerleştir';
            app.YolPlanlaButton = uibutton(app.LeftPanel, 'push');
            app.YolPlanlaButton.ButtonPushedFcn = createCallbackFcn(app, @YolPlanlaButtonPushed, true);
            app.YolPlanlaButton.Text = 'Yol Planla';
            app.SimlasyonubalatButton = uibutton(app.LeftPanel, 'push');
            app.SimlasyonubalatButton.ButtonPushedFcn = createCallbackFcn(app, @SimlasyonubalatButtonPushed, true);
            app.SimlasyonubalatButton.Text = 'Simülasyonu başlat';
            app.PlanlanmRotaCheckBox = uicheckbox(app.LeftPanel);
            app.PlanlanmRotaCheckBox.ValueChangedFcn = createCallbackFcn(app, @PlanlanmRotaCheckBoxValueChanged, true);
            app.PlanlanmRotaCheckBox.Text = 'Planlanmış Rota';
            app.GidilenYolCheckBox = uicheckbox(app.LeftPanel);
            app.GidilenYolCheckBox.ValueChangedFcn = createCallbackFcn(app, @GidilenYolCheckBoxValueChanged, true);
            app.GidilenYolCheckBox.Text = 'Gidilen Yol';
            app.arpmanoktalarCheckBox = uicheckbox(app.LeftPanel);
            app.arpmanoktalarCheckBox.ValueChangedFcn = createCallbackFcn(app, @arpmanoktalarCheckBoxValueChanged, true);
            app.arpmanoktalarCheckBox.Text = 'Çarpışma noktaları';
            app.RightPanel = uipanel(app.GridLayout);
            app.TabGroup = uitabgroup(app.RightPanel);
            app.Tab = uitab(app.TabGroup);
            app.Tab.SizeChangedFcn = createCallbackFcn(app, @TabSizeChanged, true);
            app.Tab.Title = 'Tab';
            app.Tab.ButtonDownFcn = createCallbackFcn(app, @TabButtonDown, true);
            app.UIAxes = uiaxes(app.Tab);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FleetManager_240201124

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end